classdef CenarioA1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image                     matlab.ui.control.Image
        GrficosPanel              matlab.ui.container.Panel
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        ConfiguraesPanel          matlab.ui.container.Panel
        FaceplateLC1Panel         matlab.ui.container.Panel
        MetrosLabel               matlab.ui.control.Label
        SPSpinnerLabel            matlab.ui.control.Label
        SPSpinner                 matlab.ui.control.Spinner
        Label                     matlab.ui.control.Label
        MVSpinnerLabel            matlab.ui.control.Label
        MVSpinner                 matlab.ui.control.Spinner
        MetrosLabel_2             matlab.ui.control.Label
        PVLabel                   matlab.ui.control.Label
        PV                        matlab.ui.control.NumericEditField
        AberturadaHV1Panel        matlab.ui.container.Panel
        Label_2                   matlab.ui.control.Label
        HV1Spinner                matlab.ui.control.Spinner
        SintoniadaLC1Panel        matlab.ui.container.Panel
        PBSpinnerLabel            matlab.ui.control.Label
        PBSpinner                 matlab.ui.control.Spinner
        TISpinnerLabel            matlab.ui.control.Label
        TISpinner                 matlab.ui.control.Spinner
        TDSpinnerLabel            matlab.ui.control.Label
        TDSpinner                 matlab.ui.control.Spinner
        VelocidadedeSimulaoPanel  matlab.ui.container.Panel
        ButtonGroup               matlab.ui.container.ButtonGroup
        xButton                   matlab.ui.control.RadioButton
        xButton_2                 matlab.ui.control.RadioButton
        xButton_3                 matlab.ui.control.RadioButton
        IniciarSimulaoButton      matlab.ui.control.Button
        MododeControlePanel       matlab.ui.container.Panel
        MododeControleSwitch      matlab.ui.control.Switch
        PausarSimulaoSwitchLabel  matlab.ui.control.Label
        PausarSimulaoSwitch       matlab.ui.control.Switch
        FI1EditFieldLabel         matlab.ui.control.Label
        FI1EditField              matlab.ui.control.NumericEditField
        FI2EditFieldLabel         matlab.ui.control.Label
        FI2EditField              matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarSimulaoButton
        function IniciarSimulaoButtonPushed(app, event)
            clc

            %-------------------------Parâmetros Iniciais-----------------------------%
            
            Cv      = 0.1;            %Constante de escoamento da válvula.
            Fi      = 21.85*.02;      %Vazão Inicial de água.
            g       = 9.783;          %Aceleração da Gravidade.
            A       = 05;             %Área da base do tanque.
            HF      = 05;             %Nível inicial.
            Erro    = [0];
            dErro   = [0];
            MV      = str2double(string(app.MVSpinner.Value));
            REF     = str2double(string(app.SPSpinner.Value));
            
            TRAN    = 100;            % Time Range
            
            MV_H    = MV*ones(1,TRAN);
            HF_H    = HF*ones(1,TRAN);
            REF_H   = REF*ones(1,TRAN);
            
            time = 0:1:TRAN-1;
            
            p1 = plot(app.UIAxes,time,HF_H,time,REF_H);
            p2 = plot(app.UIAxes2,time,MV_H);

                        
            %-------------------------------------------------------------------------%   
            for t=1:100000
                if app.PausarSimulaoSwitch.Value == "Off"
                    pause(1)
                elseif app.PausarSimulaoSwitch.Value == "On"
                    tic
                    REF = str2double(string(app.SPSpinner.Value));
                    
                    MV = str2double(string(app.MVSpinner.Value));
                    PB = str2double(string(app.PBSpinner.Value));
                    Ti = str2double(string(app.TISpinner.Value));
                    Td = str2double(string(app.TDSpinner.Value));
                    Cv = str2double(string(app.HV1Spinner.Value));
                    Cv = 0.0025*Cv;
                                                
                    if app.xButton.Value == 1
                        for i=1:1
                            REF_H   = [REF_H(2:TRAN) REF];
                            HF      = SIM(Cv,Fi,g,A,HF);                                        % Resolve EDO numericamente
                            HF_H    = [HF_H(2:TRAN) HF];
                            
                            HF(end) = min(10, max(0,HF(end)));
                            
                            if app.MododeControleSwitch.Value == "Auto"
                                SUM = (MV/(100/PB) - Erro - Td*dErro)/(1/Ti);
                                dErro = ((REF-HF(end)) - Erro)*.1;                                  % Cálculo do Controlador PID - Derivada
                                Erro  = (REF-HF(end))*1;                                            % Cálculo do Controlador PID - Proporcional
                                SUM   = SUM + Erro;                                                 % Cálculo do Controlador PID - Integral
                                
                                MV    = (100/PB)*(Erro +(1/Ti)*SUM +Td*dErro);                      % Cálculo do Controlador PID - Soma das Três partes
                                MV    = min(100, max(0,MV));                                        % Limitação máxima e mínima das MV.
                            end
                            
                            MV_H   = [MV_H(2:TRAN) MV];
                            Fi      = .02*MV;
        
                        end
                    elseif app.xButton_2.Value == 1
                        for i=1:10
                            REF_H   = [REF_H(2:TRAN) REF];
                            HF      = SIM(Cv,Fi,g,A,HF);                                        % Resolve EDO numericamente
                            HF_H    = [HF_H(2:TRAN) HF];
                            
                            HF(end) = min(10, max(0,HF(end)));
                            
                            if app.MododeControleSwitch.Value == "Auto"
                                SUM = (MV/(100/PB) - Erro - Td*dErro)/(1/Ti);
                                dErro = ((REF-HF(end)) - Erro)*.1;                                  % Cálculo do Controlador PID - Derivada
                                Erro  = (REF-HF(end))*1;                                            % Cálculo do Controlador PID - Proporcional
                                SUM   = SUM + Erro;                                                 % Cálculo do Controlador PID - Integral
                                
                                MV    = (100/PB)*(Erro +(1/Ti)*SUM +Td*dErro);                      % Cálculo do Controlador PID - Soma das Três partes
                                MV    = min(100, max(0,MV));                                        % Limitação máxima e mínima das MV.
                            end
                            
                            MV_H   = [MV_H(2:TRAN) MV];
                            Fi      = .02*MV;
        
                        end
                    elseif app.xButton_3.Value == 1
                        for i=1:20
                            REF_H   = [REF_H(2:TRAN) REF];
                            HF      = SIM(Cv,Fi,g,A,HF);                                        % Resolve EDO numericamente
                            HF_H    = [HF_H(2:TRAN) HF];
                            
                            HF(end) = min(10, max(0,HF(end)));
                            
                            if app.MododeControleSwitch.Value == "Auto"
                                SUM = (MV/(100/PB) - Erro - Td*dErro)/(1/Ti);
                                dErro = ((REF-HF(end)) - Erro)*.1;                                  % Cálculo do Controlador PID - Derivada
                                Erro  = (REF-HF(end))*1;                                            % Cálculo do Controlador PID - Proporcional
                                SUM   = SUM + Erro;                                                 % Cálculo do Controlador PID - Integral
                                
                                MV    = (100/PB)*(Erro +(1/Ti)*SUM +Td*dErro);                      % Cálculo do Controlador PID - Soma das Três partes
                                MV    = min(100, max(0,MV));                                        % Limitação máxima e mínima das MV.
                            end
                            
                            MV_H   = [MV_H(2:TRAN) MV];
                            Fi     = .02*MV;
        
                        end
                    end
                                             
                    app.FI1EditField.Value = Fi;
                    app.FI2EditField.Value = Cv*sqrt(g*HF(end));
                    app.PV.Value = HF(end);
                    app.MVSpinner.Value = MV;
                    
                    p1(1).YData = flip(HF_H);
                    p1(2).YData = flip(REF_H);
                    p2.YData = flip(MV_H);
                    drawnow;  
                    
                    pause(max(1-toc,0));
  
                end            
            end
                                  
            %--------------------------------Funções----------------------------------%
            
            function [HF] = SIM(Cv,Fi,g,A,HF)  % EDO do balanço de massa
                ho=HF(size(HF,2));
                [T,h]= ode23(@(t,h) (Fi-Cv*sqrt(g*h))/A,[.0:.1:1],ho);
                h=h(2:11);
                HF=h(end);
            end
            
            %-------------------------------------------------------------------------%
        end

        % Callback function
        function EncerrarSimulaoButtonPushed(app, event)
            quit
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Tq1.png';
            app.GrficosPanel = uipanel(app.UIFigure);
            app.GrficosPanel.Title = 'Gráficos';
            app.UIAxes2 = uiaxes(app.GrficosPanel);
            title(app.UIAxes2, 'Variável Manipulada - LV1')
            xlabel(app.UIAxes2, 'Tempo - s')
            ylabel(app.UIAxes2, 'Abertura da Válvula - %')
            app.UIAxes2.XLim = [0 100];
            app.UIAxes2.YLim = [0 100];
            app.UIAxes2.XDir = 'reverse';
            app.UIAxes = uiaxes(app.GrficosPanel);
            title(app.UIAxes, 'Variável Controlada - LC1')
            xlabel(app.UIAxes, 'Tempo - s')
            ylabel(app.UIAxes, 'Altura do TQ-001 - m')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 10];
            app.UIAxes.XDir = 'reverse';
            app.ConfiguraesPanel = uipanel(app.UIFigure);
            app.ConfiguraesPanel.Title = 'Configurações';
            app.FaceplateLC1Panel = uipanel(app.ConfiguraesPanel);
            app.FaceplateLC1Panel.Title = 'Faceplate - LC1';
            app.MetrosLabel = uilabel(app.FaceplateLC1Panel);
            app.MetrosLabel.Text = 'Metros';
            app.SPSpinnerLabel = uilabel(app.FaceplateLC1Panel);
            app.SPSpinnerLabel.Text = 'SP';
            app.SPSpinner = uispinner(app.FaceplateLC1Panel);
            app.SPSpinner.Limits = [0 10];
            app.SPSpinner.Value = 5;
            app.Label = uilabel(app.FaceplateLC1Panel);
            app.Label.Text = '%';
            app.MVSpinnerLabel = uilabel(app.FaceplateLC1Panel);
            app.MVSpinnerLabel.Text = 'MV';
            app.MVSpinner = uispinner(app.FaceplateLC1Panel);
            app.MVSpinner.Limits = [0 100];
            app.MVSpinner.Value = 21.85;
            app.MetrosLabel_2 = uilabel(app.FaceplateLC1Panel);
            app.MetrosLabel_2.Text = 'Metros';
            app.PVLabel = uilabel(app.FaceplateLC1Panel);
            app.PVLabel.Text = 'PV';
            app.PV = uieditfield(app.FaceplateLC1Panel, 'numeric');
            app.PV.Limits = [0 10];
            app.PV.Value = 5;
            app.AberturadaHV1Panel = uipanel(app.ConfiguraesPanel);
            app.AberturadaHV1Panel.Title = 'Abertura da HV1';
            app.Label_2 = uilabel(app.AberturadaHV1Panel);
            app.Label_2.Text = '%';
            app.HV1Spinner = uispinner(app.AberturadaHV1Panel);
            app.HV1Spinner.Limits = [0 100];
            app.HV1Spinner.Value = 25;
            app.SintoniadaLC1Panel = uipanel(app.ConfiguraesPanel);
            app.SintoniadaLC1Panel.Title = 'Sintonia da LC1';
            app.PBSpinnerLabel = uilabel(app.SintoniadaLC1Panel);
            app.PBSpinnerLabel.Text = 'PB';
            app.PBSpinner = uispinner(app.SintoniadaLC1Panel);
            app.PBSpinner.Limits = [0 1000];
            app.PBSpinner.Value = 100;
            app.TISpinnerLabel = uilabel(app.SintoniadaLC1Panel);
            app.TISpinnerLabel.Text = 'TI';
            app.TISpinner = uispinner(app.SintoniadaLC1Panel);
            app.TISpinner.Limits = [1 1000];
            app.TISpinner.Value = 100;
            app.TDSpinnerLabel = uilabel(app.SintoniadaLC1Panel);
            app.TDSpinnerLabel.Text = 'TD';
            app.TDSpinner = uispinner(app.SintoniadaLC1Panel);
            app.TDSpinner.Limits = [0 100];
            app.VelocidadedeSimulaoPanel = uipanel(app.ConfiguraesPanel);
            app.VelocidadedeSimulaoPanel.Title = 'Velocidade de Simulação';
            app.ButtonGroup = uibuttongroup(app.VelocidadedeSimulaoPanel);
            app.xButton = uiradiobutton(app.ButtonGroup);
            app.xButton.Text = '1x';
            app.xButton.Value = true;
            app.xButton_2 = uiradiobutton(app.ButtonGroup);
            app.xButton_2.Text = '10x';
            app.xButton_3 = uiradiobutton(app.ButtonGroup);
            app.xButton_3.Text = '20x';
            app.IniciarSimulaoButton = uibutton(app.ConfiguraesPanel, 'push');
            app.IniciarSimulaoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarSimulaoButtonPushed, true);
            app.IniciarSimulaoButton.Text = {'Iniciar'; 'Simulação'};
            app.MododeControlePanel = uipanel(app.ConfiguraesPanel);
            app.MododeControlePanel.Title = 'Modo de Controle';
            app.MododeControleSwitch = uiswitch(app.MododeControlePanel, 'slider');
            app.MododeControleSwitch.Items = {'Manual', 'Auto'};
            app.MododeControleSwitch.Value = 'Manual';
            app.PausarSimulaoSwitchLabel = uilabel(app.ConfiguraesPanel);
            app.PausarSimulaoSwitchLabel.Text = {'Pausar'; 'Simulação'};
            app.PausarSimulaoSwitch = uiswitch(app.ConfiguraesPanel, 'slider');
            app.PausarSimulaoSwitch.Value = 'On';
            app.FI1EditFieldLabel = uilabel(app.UIFigure);
            app.FI1EditFieldLabel.Text = 'FI1';
            app.FI1EditField = uieditfield(app.UIFigure, 'numeric');
            app.FI1EditField.ValueDisplayFormat = '%.3f';
            app.FI2EditFieldLabel = uilabel(app.UIFigure);
            app.FI2EditFieldLabel.Text = 'FI2';
            app.FI2EditField = uieditfield(app.UIFigure, 'numeric');
            app.FI2EditField.ValueDisplayFormat = '%.3f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CenarioA1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end