classdef CenarioA3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image                     matlab.ui.control.Image
        GrficosPanel              matlab.ui.container.Panel
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        ConfiguraesPanel          matlab.ui.container.Panel
        SintoniaLC1Panel          matlab.ui.container.Panel
        PBSpinnerLabel            matlab.ui.control.Label
        PBSpinner                 matlab.ui.control.Spinner
        TISpinnerLabel            matlab.ui.control.Label
        TISpinner                 matlab.ui.control.Spinner
        TDSpinnerLabel            matlab.ui.control.Label
        TDSpinner                 matlab.ui.control.Spinner
        AberturadaHV1Panel        matlab.ui.container.Panel
        Label_2                   matlab.ui.control.Label
        HV1Spinner                matlab.ui.control.Spinner
        VelocidadedeSimulaoPanel  matlab.ui.container.Panel
        ButtonGroup               matlab.ui.container.ButtonGroup
        xButton                   matlab.ui.control.RadioButton
        xButton_2                 matlab.ui.control.RadioButton
        xButton_3                 matlab.ui.control.RadioButton
        IniciarSimulaoButton      matlab.ui.control.Button
        SintoniaLC2Panel          matlab.ui.container.Panel
        PBSpinner_2Label          matlab.ui.control.Label
        PBSpinner_2               matlab.ui.control.Spinner
        TISpinner_2Label          matlab.ui.control.Label
        TISpinner_2               matlab.ui.control.Spinner
        TDSpinner_2Label          matlab.ui.control.Label
        TDSpinner_2               matlab.ui.control.Spinner
        SetPointPanel             matlab.ui.container.Panel
        metrosLabel               matlab.ui.control.Label
        SPSpinner                 matlab.ui.control.Spinner
        PausarSimulaoSwitchLabel  matlab.ui.control.Label
        PausarSimulaoSwitch       matlab.ui.control.Switch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarSimulaoButton
        function IniciarSimulaoButtonPushed(app, event)
            
            clc
            %-------------------------Parâmetros Iniciais-----------------------------%
            
            Cv1     = 0.0178727;      %Constante de escoamento da válvula.
            Cv2     = 0.0178727;      %Constante de escoamento da válvula.
            g       = 9.783;          %Aceleração da Gravidade.
            A       = 05;             %Área da base do tanque.
            MV      = 1000*[Cv1 Cv2];
            HF1     = 05;             %Nível inicial.
            HF2     = 05;
            Erro    = [0 0];
            dErro   = [0 0];
            REF     = [str2double(string(app.SPSpinner.Value)) str2double(string(app.SPSpinner.Value))];
            
            TRAN    = 100;            % Time Range

            HF_H    = [HF1 HF2]'*ones(1,TRAN);
            REF_H   = REF'*ones(1,TRAN);
            
            time = 0:1:TRAN-1;
            
            p1 = plot(app.UIAxes,time,HF_H(1,1:TRAN),time,REF_H(1,1:TRAN));
            p2 = plot(app.UIAxes2,time,HF_H(2,1:TRAN),time,REF_H(2,1:TRAN));
            
            %-------------------------------------------------------------------------%
            for t=1:100000
                if app.PausarSimulaoSwitch.Value == "Off"
                    pause(1)
                elseif app.PausarSimulaoSwitch.Value == "On"
                
                    tic
                    REF = [str2double(string(app.SPSpinner.Value)) str2double(string(app.SPSpinner.Value))];
                    
                    PB1     = str2double(string(app.PBSpinner.Value));
                    Ti1     = str2double(string(app.TISpinner.Value));
                    Td1     = str2double(string(app.TDSpinner.Value));
                    PB2     = str2double(string(app.PBSpinner_2.Value));
                    Ti2     = str2double(string(app.TISpinner_2.Value));
                    Td2     = str2double(string(app.TDSpinner_2.Value));
                    
                    PB      = [PB1 PB2];
                    Ti      = [Ti1 Ti2];
                    Td      = [Td1 Td2];
                     
                    Fi = 0.1*str2double(string(app.HV1Spinner.Value));
                    
                    if app.xButton.Value == 1
                        for i=1:1
                            HF1    = SIM1(Cv1,Fi,g,A,HF1);                                     % Resolve EDO numericamente
                            HF2    = SIM2(Cv2,Fi,g,A,HF2);                                     % Resolve EDO numericamente
                            HF1    = min(10, max(0,HF1));
                            HF2    = min(10, max(0,HF2));
                            
                            HF_H(1,1:TRAN) = [HF_H(1,2:TRAN) HF1];
                            HF_H(2,1:TRAN) = [HF_H(2,2:TRAN) HF2];
                            REF_H(1,1:TRAN) = [REF_H(1,2:TRAN) REF(1)];
                            REF_H(2,1:TRAN) = [REF_H(2,2:TRAN) REF(2)];
                            
                            SUM = (MV./(100./PB) + Erro - Td.*dErro)./(1./Ti);                  % Cálculo para troca de Manual para Auto
                            
                            dErro = ((REF-[HF1 HF2]) - Erro)*[-.1 0;0 -.1];                     % Cálculo da Variação do Erro
                            Erro  = (REF-[HF1 HF2])*[-1 0; 0 -1];                               % Cálculo do Erro
                            SUM   = SUM + Erro;                                                 % Cálculo do Controlador PID - Integral
                            
                            MV    = (100./PB).*(Erro +(1./Ti).*SUM +Td.*dErro);                 % Cálculo do Controlador PID - Soma das Três partes
                            MV    = min(100, max(0,MV));                                        % Limitação máxima e mínima das MV.
                            
                            Cv1   = .02*MV(1);
                            Cv2   = .02*MV(2);
                           
                        end
                    elseif app.xButton_2.Value == 1
                        for i=1:10
                            HF1    = SIM1(Cv1,Fi,g,A,HF1);                                     % Resolve EDO numericamente
                            HF2    = SIM2(Cv2,Fi,g,A,HF2);                                     % Resolve EDO numericamente
                            HF1    = min(10, max(0,HF1));
                            HF2    = min(10, max(0,HF2));
                            
                            HF_H(1,1:TRAN) = [HF_H(1,2:TRAN) HF1];
                            HF_H(2,1:TRAN) = [HF_H(2,2:TRAN) HF2];
                            REF_H(1,1:TRAN) = [REF_H(1,2:TRAN) REF(1)];
                            REF_H(2,1:TRAN) = [REF_H(2,2:TRAN) REF(2)];
                            
                            SUM = (MV./(100./PB) + Erro - Td.*dErro)./(1./Ti);                  % Cálculo para troca de Manual para Auto
                            
                            dErro = ((REF-[HF1 HF2]) - Erro)*[-.1 0;0 -.1];                     % Cálculo da Variação do Erro
                            Erro  = (REF-[HF1 HF2])*[-1 0; 0 -1];                               % Cálculo do Erro
                            SUM   = SUM + Erro;                                                 % Cálculo do Controlador PID - Integral
                            
                            MV    = (100./PB).*(Erro +(1./Ti).*SUM +Td.*dErro);                 % Cálculo do Controlador PID - Soma das Três partes
                            MV    = min(100, max(0,MV));                                        % Limitação máxima e mínima das MV.
                            
                            Cv1   = .02*MV(1);
                            Cv2   = .02*MV(2);
                           
                        end
                    elseif app.xButton_3.Value == 1
                        for i=1:20
                            HF1    = SIM1(Cv1,Fi,g,A,HF1);                                     % Resolve EDO numericamente
                            HF2    = SIM2(Cv2,Fi,g,A,HF2);                                     % Resolve EDO numericamente
                            HF1    = min(10, max(0,HF1));
                            HF2    = min(10, max(0,HF2));
                            
                            HF_H(1,1:TRAN) = [HF_H(1,2:TRAN) HF1];
                            HF_H(2,1:TRAN) = [HF_H(2,2:TRAN) HF2];
                            REF_H(1,1:TRAN) = [REF_H(1,2:TRAN) REF(1)];
                            REF_H(2,1:TRAN) = [REF_H(2,2:TRAN) REF(2)];
                            
                            SUM = (MV./(100./PB) + Erro - Td.*dErro)./(1./Ti);                  % Cálculo para troca de Manual para Auto
                            
                            dErro = ((REF-[HF1 HF2]) - Erro)*[-.1 0;0 -.1];                     % Cálculo da Variação do Erro
                            Erro  = (REF-[HF1 HF2])*[-1 0; 0 -1];                               % Cálculo do Erro
                            SUM   = SUM + Erro;                                                 % Cálculo do Controlador PID - Integral
                            
                            MV    = (100./PB).*(Erro +(1./Ti).*SUM +Td.*dErro);                 % Cálculo do Controlador PID - Soma das Três partes
                            MV    = min(100, max(0,MV));                                        % Limitação máxima e mínima das MV.
                            
                            Cv1   = .02*MV(1);
                            Cv2   = .02*MV(2);
                           
                        end
                    end
                    
                
                p1(1).YData = flip(HF_H(1,1:TRAN));
                p1(2).YData = flip(REF_H(1,1:TRAN));
                p2(1).YData = flip(HF_H(2,1:TRAN));
                p2(2).YData = flip(REF_H(2,1:TRAN));
                drawnow;  
                    
                pause(max(1-toc,0));
                
                end
            end
            
            %--------------------------------Funções----------------------------------%
            
            function [HF1] = SIM1(Cv1,Fi,g,A,HF)  % EDO do balanço de massa
                ho=HF(size(HF,2));
                [T,h]= ode23(@(t,h) (Fi-Cv1*sqrt(g*h))/A,[.0:.1:1],ho);
                h=h(2:11);
                HF1=h(end);
            end
            
            function [HF2] = SIM2(Cv2,Fi,g,A,HF2)  % EDO do balanço de massa
                ho=HF2(size(HF2,2));
                [T,h]= ode23(@(t,h) (Fi-Cv2*sqrt(g*h))/A,[.0:.1:1],ho);
                h=h(2:11);
                HF2=h(end);
            end
            %-------------------------------------------------------------------------%
        end

        % Callback function
        function EncerrarSimulaoButtonPushed(app, event)
            quit
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Tq3.png';
            app.GrficosPanel = uipanel(app.UIFigure);
            app.GrficosPanel.Title = 'Gráficos';
            app.UIAxes2 = uiaxes(app.GrficosPanel);
            title(app.UIAxes2, 'Nível do Tanque - LC2 ')
            xlabel(app.UIAxes2, 'Tempo - s')
            ylabel(app.UIAxes2, 'Abertura da Válvula - %')
            app.UIAxes2.XLim = [0 100];
            app.UIAxes2.YLim = [0 10];
            app.UIAxes2.XDir = 'reverse';
            app.UIAxes = uiaxes(app.GrficosPanel);
            title(app.UIAxes, 'Nível do Tanque - LC1')
            xlabel(app.UIAxes, 'Tempo - s')
            ylabel(app.UIAxes, 'Altura do TQ-001 - m')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 10];
            app.UIAxes.XDir = 'reverse';
            app.ConfiguraesPanel = uipanel(app.UIFigure);
            app.ConfiguraesPanel.Title = 'Configurações';
            app.SintoniaLC1Panel = uipanel(app.ConfiguraesPanel);
            app.SintoniaLC1Panel.Title = 'Sintonia - LC-1';
            app.PBSpinnerLabel = uilabel(app.SintoniaLC1Panel);
            app.PBSpinnerLabel.Text = 'PB';
            app.PBSpinner = uispinner(app.SintoniaLC1Panel);
            app.PBSpinner.Limits = [0 1000];
            app.PBSpinner.Value = 500;
            app.TISpinnerLabel = uilabel(app.SintoniaLC1Panel);
            app.TISpinnerLabel.Text = 'TI';
            app.TISpinner = uispinner(app.SintoniaLC1Panel);
            app.TISpinner.Limits = [0 1000];
            app.TISpinner.Value = 500;
            app.TDSpinnerLabel = uilabel(app.SintoniaLC1Panel);
            app.TDSpinnerLabel.Text = 'TD';
            app.TDSpinner = uispinner(app.SintoniaLC1Panel);
            app.TDSpinner.Limits = [0 100];
            app.AberturadaHV1Panel = uipanel(app.ConfiguraesPanel);
            app.AberturadaHV1Panel.Title = 'Abertura da HV1';
            app.Label_2 = uilabel(app.AberturadaHV1Panel);
            app.Label_2.Text = '%';
            app.HV1Spinner = uispinner(app.AberturadaHV1Panel);
            app.HV1Spinner.Limits = [0 100];
            app.HV1Spinner.Value = 25;
            app.VelocidadedeSimulaoPanel = uipanel(app.ConfiguraesPanel);
            app.VelocidadedeSimulaoPanel.Title = 'Velocidade de Simulação';
            app.ButtonGroup = uibuttongroup(app.VelocidadedeSimulaoPanel);
            app.xButton = uiradiobutton(app.ButtonGroup);
            app.xButton.Text = '1x';
            app.xButton.Value = true;
            app.xButton_2 = uiradiobutton(app.ButtonGroup);
            app.xButton_2.Text = '10x';
            app.xButton_3 = uiradiobutton(app.ButtonGroup);
            app.xButton_3.Text = '20x';
            app.IniciarSimulaoButton = uibutton(app.ConfiguraesPanel, 'push');
            app.IniciarSimulaoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarSimulaoButtonPushed, true);
            app.IniciarSimulaoButton.Text = {'Iniciar'; 'Simulação'};
            app.SintoniaLC2Panel = uipanel(app.ConfiguraesPanel);
            app.SintoniaLC2Panel.Title = 'Sintonia - LC-2';
            app.PBSpinner_2Label = uilabel(app.SintoniaLC2Panel);
            app.PBSpinner_2Label.Text = 'PB';
            app.PBSpinner_2 = uispinner(app.SintoniaLC2Panel);
            app.PBSpinner_2.Limits = [0 1000];
            app.PBSpinner_2.Value = 300;
            app.TISpinner_2Label = uilabel(app.SintoniaLC2Panel);
            app.TISpinner_2Label.Text = 'TI';
            app.TISpinner_2 = uispinner(app.SintoniaLC2Panel);
            app.TISpinner_2.Limits = [0 1000];
            app.TISpinner_2.Value = 800;
            app.TDSpinner_2Label = uilabel(app.SintoniaLC2Panel);
            app.TDSpinner_2Label.Text = 'TD';
            app.TDSpinner_2 = uispinner(app.SintoniaLC2Panel);
            app.TDSpinner_2.Limits = [0 100];
            app.SetPointPanel = uipanel(app.ConfiguraesPanel);
            app.SetPointPanel.Title = 'Set-Point';
            app.metrosLabel = uilabel(app.SetPointPanel);
            app.metrosLabel.Text = 'metros';
            app.SPSpinner = uispinner(app.SetPointPanel);
            app.SPSpinner.Limits = [0 10];
            app.SPSpinner.Value = 5;
            app.PausarSimulaoSwitchLabel = uilabel(app.ConfiguraesPanel);
            app.PausarSimulaoSwitchLabel.Text = {'Pausar'; 'Simulação'};
            app.PausarSimulaoSwitch = uiswitch(app.ConfiguraesPanel, 'slider');
            app.PausarSimulaoSwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CenarioA3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end