classdef CenarioB2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        ConfiguraesPanel               matlab.ui.container.Panel
        FaceplateFC1Panel              matlab.ui.container.Panel
        tonhLabel                      matlab.ui.control.Label
        SPSpinnerLabel                 matlab.ui.control.Label
        SPSpinner                      matlab.ui.control.Spinner
        Label                          matlab.ui.control.Label
        MVSpinnerLabel                 matlab.ui.control.Label
        MVSpinner                      matlab.ui.control.Spinner
        tonhLabel_2                    matlab.ui.control.Label
        PVLabel                        matlab.ui.control.Label
        PV                             matlab.ui.control.NumericEditField
        IniciarReiniciarSimulaoButton  matlab.ui.control.Button
        FaceplateTC1Panel              matlab.ui.container.Panel
        CLabel                         matlab.ui.control.Label
        SPSpinner_2Label               matlab.ui.control.Label
        SPSpinner_2                    matlab.ui.control.Spinner
        Label_2                        matlab.ui.control.Label
        MVSpinner_2Label               matlab.ui.control.Label
        MVSpinner_2                    matlab.ui.control.Spinner
        CLabel_2                       matlab.ui.control.Label
        PVLabel_2                      matlab.ui.control.Label
        PV_2                           matlab.ui.control.NumericEditField
        VeldeSimulaoPanel              matlab.ui.container.Panel
        ButtonGroup                    matlab.ui.container.ButtonGroup
        xButton                        matlab.ui.control.RadioButton
        xButton_2                      matlab.ui.control.RadioButton
        xButton_3                      matlab.ui.control.RadioButton
        MododeControlePanel            matlab.ui.container.Panel
        MododeControleSwitch           matlab.ui.control.Switch
        TempdoHeaderTI2Panel           matlab.ui.container.Panel
        CLabel_3                       matlab.ui.control.Label
        TsSpinner                      matlab.ui.control.Spinner
        FaceplateFC2Panel              matlab.ui.container.Panel
        tonhLabel_3                    matlab.ui.control.Label
        SPSpinner_3Label               matlab.ui.control.Label
        SPSpinner_3                    matlab.ui.control.Spinner
        Label_3                        matlab.ui.control.Label
        MVSpinner_3Label               matlab.ui.control.Label
        MVSpinner_3                    matlab.ui.control.Spinner
        tonhLabel_4                    matlab.ui.control.Label
        PVLabel_3                      matlab.ui.control.Label
        PV_3                           matlab.ui.control.NumericEditField
        PressodoHeaderPI1Panel         matlab.ui.container.Panel
        kgfcmLabel                     matlab.ui.control.Label
        P1Spinner                      matlab.ui.control.Spinner
        FC2PRDCASPanel                 matlab.ui.container.Panel
        MododeControleSwitch_2         matlab.ui.control.Switch
        PausarSimulaoSwitchLabel       matlab.ui.control.Label
        PausarSimulaoSwitch            matlab.ui.control.Switch
        GrficosPanel                   matlab.ui.container.Panel
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        FC1EditField                   matlab.ui.control.NumericEditField
        TI1EditField                   matlab.ui.control.NumericEditField
        PI1EditField                   matlab.ui.control.NumericEditField
        FC2EditField                   matlab.ui.control.NumericEditField
        TI2EditField                   matlab.ui.control.NumericEditField
        TI3EditField                   matlab.ui.control.NumericEditField
        TC1EditField                   matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarReiniciarSimulaoButton
        function IniciarReiniciarSimulaoButtonPushed(app, event)
            clc
            %-------------------------Parâmetros Iniciais-----------------------------%
            Cv1     = 100;          % Constante de escoamento da linha.
            Cv2     = 39.93;        % Constante de escoamento da válvula.
            Cv3     = 100;
            Cv4     = 18.18;
            P       = 17.49;         % Pressão inicial.
            P1      = 20;           % Pressão do início do Header.
            P2      = 01;           % Pressão do final do Header.
            P3      = 30;
            P4      = 29.1981;
            P5      = 05;
            Ro      = 1000;         % Densidade do fluído controlado.
            A       = 150;          % Área de troca térmica do EA-001.
            Ts      = 200;          % Temperatura do fluído de aquecimento.
            Uw      = 2e7;          % Coef. Global de troca térmica do fluído de aquecimento kcal/ºC.min.
            Cp      = 2e6;          % Calor específico do fluído aquecido   ;
            TF      = 100;
            Te      = 40;
            Erro    = [0 0 0];
            dErro   = [0 0 0];
            Q       = Cv2*sqrt((P(end)-P2)/Ro);
            QTD     = [Q Q Q Q Q Q Q Q Q Q Q Q Q Q Q];
            U       = Cv4*sqrt((P4(end)-P5)/(2*Ro));
            UTD     = [U U U U U U U U U U];
            REF     = [5 100 2];
            PB1     = 100;
            Ti1     = 100;
            Td1     = 000;
            PB2     = 999;
            Ti2     = 600;
            Td2     = 010;
            PB3     = 010;
            Ti3     = 050;
            Td3     = 000;
            
            TRAN    = 100;            % Time Range

            Q_H     = Q*ones(1,TRAN);
            TF_H    = TF*ones(1,TRAN);
            REF_H   = REF'*ones(1,TRAN);
            
            time = 0:1:TRAN-1;
            
            p1 = plot(app.UIAxes,time,Q_H(1:TRAN),time,REF_H(1,1:TRAN));
            p2 = plot(app.UIAxes2,time,TF_H(1:TRAN),time,REF_H(2,1:TRAN));
            
            %-------------------------------------------------------------------------%
            for i=1:1:100000
                if app.PausarSimulaoSwitch.Value == "Off"
                    pause(1)
                elseif app.PausarSimulaoSwitch.Value == "On"
                    tic
                    
                    Ts      = app.TsSpinner.Value;
                    P3      = app.P1Spinner.Value;
                    MV1     = str2double(string(app.MVSpinner.Value));
                    MV2     = str2double(string(app.MVSpinner_2.Value));
                    MV3     = str2double(string(app.MVSpinner_3.Value));
                    
                    MV      = [MV1 MV2 MV3];
                    PB      = [PB1 PB2 PB3];
                    Ti      = [Ti1 Ti2 Ti3];
                    Td      = [Td1 Td2 Td3];
                    
                    if app.xButton.Value == 1
                        REF    = [str2double(string(app.SPSpinner.Value)) str2double(string(app.SPSpinner_2.Value)) str2double(string(app.MVSpinner_2.Value))/20];
                                                                        
                        Cv2    = 1*MV(1);
                        Cv4    = 1*MV(3);
                        Qe     = Cv2*sqrt((P(end)-P2)/Ro);
                        Ue     = Cv4*sqrt((P4(end)-P5)/(2*Ro));                       
                        UTD    = [UTD(2:10) Ue];
                        QTD    = [QTD(2:15) Qe];
                        
                        [P]    = SIM1(P,Cv1,Cv2,P1,P2);
                        [TF]   = SIM2(Qe,QTD,Ro,Cp,UTD,Uw,A,TF,Ts,Te);
                        [P4]   = SIM3(P3,Cv3,Cv4,P4,P5);
                        
                        Q_H(1:TRAN) = [Q_H(2:TRAN) Qe];
                        TF_H(1:TRAN) = [TF_H(2:TRAN) TF];
                        REF_H(1,1:TRAN) = [REF_H(1,2:TRAN) REF(1)];
                        REF_H(2,1:TRAN) = [REF_H(2,2:TRAN) REF(2)];
                        
                        if app.MododeControleSwitch.Value == "Auto"
                            SUM = (MV./(100./PB) + Erro - Td.*dErro)./(1./Ti);                  % Cálculo para troca de Manual para Auto
                            
                            dErro = ((REF-[Qe TF(end) Ue]) - Erro)*[.1 0 0;0 .01 0;0 0 0.2];    % Cálculo da Variação do Erro
                            Erro  = (REF-[Qe TF(end) Ue])*[1 0 0; 0 .1 0;0 0 .5];               % Cálculo do Erro
                            SUM   = SUM + Erro;                                                 % Cálculo do Controlador PID - Integral
                            
                            MV    = (100./PB).*(Erro +(1./Ti).*SUM +Td.*dErro);                 % Cálculo do Controlador PID - Soma das Três partes
                            MV    = min(100, max(0.0001,MV));                                   % Limitação máxima e mínima das MV.
                        end
                        
                        if app.MododeControleSwitch_2.Value == "PRD"
                            %SUMM    = (MV(3)/(100./PB(2)) + Erro(2) - Td(2).*dErro(2))./(1./Ti(2));
                            %MV(2)   = (100./PB(2)).*(Erro(2) +(1./Ti(2)).*SUMM +Td(2).*dErro(2));
                            MV(3)   = MV(2);
                        end
                        
                        app.FC1EditField.Value = Qe(end);
                        app.FC2EditField.Value = Ue;
                        app.PI1EditField.Value = app.P1Spinner.Value;
                        app.TC1EditField.Value = TF(end);
                        app.TI1EditField.Value = Te;
                        app.TI2EditField.Value = Ts;
                        app.TI3EditField.Value = ((Qe(end)/Ro)*Cp*(-TF(end)+Te))/((Ue/(2*Ro))*5e6)+Ts;
                        app.MVSpinner.Value = MV(1);
                        app.MVSpinner_2.Value = MV(2);
                        app.MVSpinner_3.Value = MV(3);
                        app.SPSpinner_3.Value = MV(2)/20;
                        app.PV.Value = Qe(end);
                        app.PV_2.Value = TF(end);
                        app.PV_3.Value = U(end);
                        
                    end
                    if app.xButton_2.Value == 1
                        for t=1:1:10
                            REF    = [str2double(string(app.SPSpinner.Value)) str2double(string(app.SPSpinner_2.Value)) str2double(string(app.MVSpinner_2.Value))/20];
                                                                            
                            Cv2    = 1*MV(1);
                            Cv4    = 1*MV(3);
                            Qe     = Cv2*sqrt((P(end)-P2)/Ro);
                            Ue     = Cv4*sqrt((P4(end)-P5)/(2*Ro));                       
                            UTD    = [UTD(2:10) Ue];
                            QTD    = [QTD(2:15) Qe];
                            
                            [P]    = SIM1(P,Cv1,Cv2,P1,P2);
                            [TF]   = SIM2(Qe,QTD,Ro,Cp,UTD,Uw,A,TF,Ts,Te);
                            [P4]   = SIM3(P3,Cv3,Cv4,P4,P5);
                            
                            Q_H(1:TRAN) = [Q_H(2:TRAN) Qe];
                            TF_H(1:TRAN) = [TF_H(2:TRAN) TF];
                            REF_H(1,1:TRAN) = [REF_H(1,2:TRAN) REF(1)];
                            REF_H(2,1:TRAN) = [REF_H(2,2:TRAN) REF(2)];
                            
                            if app.MododeControleSwitch.Value == "Auto"
                                SUM = (MV./(100./PB) + Erro - Td.*dErro)./(1./Ti);                  % Cálculo para troca de Manual para Auto
                                
                                dErro = ((REF-[Qe TF(end) Ue]) - Erro)*[.1 0 0;0 .01 0;0 0 0.2];    % Cálculo da Variação do Erro
                                Erro  = (REF-[Qe TF(end) Ue])*[1 0 0; 0 .1 0;0 0 .5];               % Cálculo do Erro
                                SUM   = SUM + Erro;                                                 % Cálculo do Controlador PID - Integral
                                
                                MV    = (100./PB).*(Erro +(1./Ti).*SUM +Td.*dErro);                 % Cálculo do Controlador PID - Soma das Três partes
                                MV    = min(100, max(0.0001,MV));                                   % Limitação máxima e mínima das MV.
                            end
                            
                            if app.MododeControleSwitch_2.Value == "PRD"
                                %SUMM    = (MV(3)/(100./PB(2)) + Erro(2) - Td(2).*dErro(2))./(1./Ti(2));
                                %MV(2)   = (100./PB(2)).*(Erro(2) +(1./Ti(2)).*SUMM +Td(2).*dErro(2));
                                MV(3)   = MV(2);
                            end
                            
                            app.FC1EditField.Value = Qe(end);
                            app.FC2EditField.Value = Ue;
                            app.PI1EditField.Value = app.P1Spinner.Value;
                            app.TC1EditField.Value = TF(end);
                            app.TI1EditField.Value = Te;
                            app.TI2EditField.Value = Ts;
                            app.TI3EditField.Value = ((Qe(end)/Ro)*Cp*(-TF(end)+Te))/((Ue/(2*Ro))*5e6)+Ts;
                            app.MVSpinner.Value = MV(1);
                            app.MVSpinner_2.Value = MV(2);
                            app.MVSpinner_3.Value = MV(3);
                            app.SPSpinner_3.Value = MV(2)/20;
                            app.PV.Value = Qe(end);
                            app.PV_2.Value = TF(end);
                            app.PV_3.Value = U(end);
                            
                        end
                    end
                    if app.xButton_3.Value == 1
                        for t=1:1:20
                            REF    = [str2double(string(app.SPSpinner.Value)) str2double(string(app.SPSpinner_2.Value)) str2double(string(app.MVSpinner_2.Value))/20];
                                                                            
                            Cv2    = 1*MV(1);
                            Cv4    = 1*MV(3);
                            Qe     = Cv2*sqrt((P(end)-P2)/Ro);
                            Ue     = Cv4*sqrt((P4(end)-P5)/(2*Ro));                       
                            UTD    = [UTD(2:10) Ue];
                            QTD    = [QTD(2:15) Qe];
                            
                            [P]    = SIM1(P,Cv1,Cv2,P1,P2);
                            [TF]   = SIM2(Qe,QTD,Ro,Cp,UTD,Uw,A,TF,Ts,Te);
                            [P4]   = SIM3(P3,Cv3,Cv4,P4,P5);
                            
                            Q_H(1:TRAN) = [Q_H(2:TRAN) Qe];
                            TF_H(1:TRAN) = [TF_H(2:TRAN) TF];
                            REF_H(1,1:TRAN) = [REF_H(1,2:TRAN) REF(1)];
                            REF_H(2,1:TRAN) = [REF_H(2,2:TRAN) REF(2)];
                            
                            if app.MododeControleSwitch.Value == "Auto"
                                SUM = (MV./(100./PB) + Erro - Td.*dErro)./(1./Ti);                  % Cálculo para troca de Manual para Auto
                                
                                dErro = ((REF-[Qe TF(end) Ue]) - Erro)*[.1 0 0;0 .01 0;0 0 0.2];    % Cálculo da Variação do Erro
                                Erro  = (REF-[Qe TF(end) Ue])*[1 0 0; 0 .1 0;0 0 .5];               % Cálculo do Erro
                                SUM   = SUM + Erro;                                                 % Cálculo do Controlador PID - Integral
                                
                                MV    = (100./PB).*(Erro +(1./Ti).*SUM +Td.*dErro);                 % Cálculo do Controlador PID - Soma das Três partes
                                MV    = min(100, max(0.0001,MV));                                   % Limitação máxima e mínima das MV.
                            end
                            
                            if app.MododeControleSwitch_2.Value == "PRD"
                                %SUMM    = (MV(3)/(100./PB(2)) + Erro(2) - Td(2).*dErro(2))./(1./Ti(2));
                                %MV(2)   = (100./PB(2)).*(Erro(2) +(1./Ti(2)).*SUMM +Td(2).*dErro(2));
                                MV(3)   = MV(2);
                            end
                            
                            app.FC1EditField.Value = Qe(end);
                            app.FC2EditField.Value = Ue;
                            app.PI1EditField.Value = app.P1Spinner.Value;
                            app.TC1EditField.Value = TF(end);
                            app.TI1EditField.Value = Te;
                            app.TI2EditField.Value = Ts;
                            app.TI3EditField.Value = ((Qe(end)/Ro)*Cp*(-TF(end)+Te))/((Ue/(2*Ro))*5e6)+Ts;
                            app.MVSpinner.Value = MV(1);
                            app.MVSpinner_2.Value = MV(2);
                            app.MVSpinner_3.Value = MV(3);
                            app.SPSpinner_3.Value = MV(2)/20;
                            app.PV.Value = Qe(end);
                            app.PV_2.Value = TF(end);
                            app.PV_3.Value = U(end);
                            
                        end
                    end
    
                p1(1).YData = flip(Q_H(1:TRAN));
                p1(2).YData = flip(REF_H(1,1:TRAN));
                p2(1).YData = flip(TF_H(1:TRAN));
                p2(2).YData = flip(REF_H(2,1:TRAN));
                drawnow;  
                
                pause(max(1-toc,0));
                end
            end
            
            %--------------------------------Funções----------------------------------%
            
            function[P] = SIM1(P,Cv1,Cv2,P1,P2)
                [t,h] = ode23(@(t,P) Cv1^2*P1 + Cv2^2*P2 -(Cv1^2+Cv2^2)*P,[0:.1:1],P(end));
                h=h(2:11);
                P = h(end);
            end
            
            function[TF] = SIM2(Qe,QTD,Ro,Cp,UTD,Uw,A,TF,Ts,Te)
                [t,h]= ode23(@(t,T) (QTD(1)*Ro*Cp*Te -QTD(1)*Ro*Cp*T -Uw*UTD(1)*A*(T-Ts))/(Ro*Cp*QTD(1)) ,[0:.1:1],TF(end));
                h=h(2:11);
                TF = h(end);
            end
            
            function[P4] = SIM3(P3,Cv3,Cv4,P4,P5)
                [t,h]= ode23(@(t,P4) Cv3^2*P3 + Cv4^2*P5 -(Cv3^2+Cv4^2)*P4,[0:.1:1],P4(end));
                h=h(2:11);
                P4 = h(end);
            end
            
            %-------------------------------------------------------------------------%
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Ea2.png';
            app.ConfiguraesPanel = uipanel(app.UIFigure);
            app.ConfiguraesPanel.Title = 'Configurações';
            app.FaceplateFC1Panel = uipanel(app.ConfiguraesPanel);
            app.FaceplateFC1Panel.Title = 'Faceplate - FC1';
            app.tonhLabel = uilabel(app.FaceplateFC1Panel);
            app.tonhLabel.Text = 'ton/h';
            app.SPSpinnerLabel = uilabel(app.FaceplateFC1Panel);
            app.SPSpinnerLabel.Text = 'SP';
            app.SPSpinner = uispinner(app.FaceplateFC1Panel);
            app.SPSpinner.Limits = [0 10];
            app.SPSpinner.Value = 5;
            app.Label = uilabel(app.FaceplateFC1Panel);
            app.Label.Text = '%';
            app.MVSpinnerLabel = uilabel(app.FaceplateFC1Panel);
            app.MVSpinnerLabel.Text = 'MV';
            app.MVSpinner = uispinner(app.FaceplateFC1Panel);
            app.MVSpinner.Limits = [0 100];
            app.MVSpinner.ValueDisplayFormat = '%.2f';
            app.MVSpinner.Value = 38.932;
            app.tonhLabel_2 = uilabel(app.FaceplateFC1Panel);
            app.tonhLabel_2.Text = 'ton/h';
            app.PVLabel = uilabel(app.FaceplateFC1Panel);
            app.PVLabel.Text = 'PV';
            app.PV = uieditfield(app.FaceplateFC1Panel, 'numeric');
            app.PV.Limits = [0 30];
            app.PV.ValueDisplayFormat = '%.2f';
            app.PV.Value = 5;
            app.IniciarReiniciarSimulaoButton = uibutton(app.ConfiguraesPanel, 'push');
            app.IniciarReiniciarSimulaoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarReiniciarSimulaoButtonPushed, true);
            app.IniciarReiniciarSimulaoButton.Text = {'Iniciar/Reiniciar'; 'Simulação'};
            app.FaceplateTC1Panel = uipanel(app.ConfiguraesPanel);
            app.FaceplateTC1Panel.Title = 'Faceplate - TC1';
            app.CLabel = uilabel(app.FaceplateTC1Panel);
            app.CLabel.Text = 'ºC';
            app.SPSpinner_2Label = uilabel(app.FaceplateTC1Panel);
            app.SPSpinner_2Label.Text = 'SP';
            app.SPSpinner_2 = uispinner(app.FaceplateTC1Panel);
            app.SPSpinner_2.Limits = [0 200];
            app.SPSpinner_2.Value = 100;
            app.Label_2 = uilabel(app.FaceplateTC1Panel);
            app.Label_2.Text = '%';
            app.MVSpinner_2Label = uilabel(app.FaceplateTC1Panel);
            app.MVSpinner_2Label.Text = 'MV';
            app.MVSpinner_2 = uispinner(app.FaceplateTC1Panel);
            app.MVSpinner_2.Limits = [0 100];
            app.MVSpinner_2.ValueDisplayFormat = '%.2f';
            app.MVSpinner_2.Value = 18.18;
            app.CLabel_2 = uilabel(app.FaceplateTC1Panel);
            app.CLabel_2.Text = 'ºC';
            app.PVLabel_2 = uilabel(app.FaceplateTC1Panel);
            app.PVLabel_2.Text = 'PV';
            app.PV_2 = uieditfield(app.FaceplateTC1Panel, 'numeric');
            app.PV_2.Limits = [0 200];
            app.PV_2.ValueDisplayFormat = '%.2f';
            app.PV_2.Value = 100;
            app.VeldeSimulaoPanel = uipanel(app.ConfiguraesPanel);
            app.VeldeSimulaoPanel.Title = 'Vel. de Simulação';
            app.ButtonGroup = uibuttongroup(app.VeldeSimulaoPanel);
            app.xButton = uiradiobutton(app.ButtonGroup);
            app.xButton.Text = '1x';
            app.xButton.Value = true;
            app.xButton_2 = uiradiobutton(app.ButtonGroup);
            app.xButton_2.Text = '10x';
            app.xButton_3 = uiradiobutton(app.ButtonGroup);
            app.xButton_3.Text = '20x';
            app.MododeControlePanel = uipanel(app.ConfiguraesPanel);
            app.MododeControlePanel.Title = 'Modo de Controle';
            app.MododeControleSwitch = uiswitch(app.MododeControlePanel, 'slider');
            app.MododeControleSwitch.Items = {'Manual', 'Auto'};
            app.MododeControleSwitch.Value = 'Manual';
            app.TempdoHeaderTI2Panel = uipanel(app.ConfiguraesPanel);
            app.TempdoHeaderTI2Panel.Title = 'Temp. do Header - TI2';
            app.CLabel_3 = uilabel(app.TempdoHeaderTI2Panel);
            app.CLabel_3.Text = 'ºC';
            app.TsSpinner = uispinner(app.TempdoHeaderTI2Panel);
            app.TsSpinner.Limits = [150 210];
            app.TsSpinner.Value = 200;
            app.FaceplateFC2Panel = uipanel(app.ConfiguraesPanel);
            app.FaceplateFC2Panel.Title = 'Faceplate - FC2';
            app.tonhLabel_3 = uilabel(app.FaceplateFC2Panel);
            app.tonhLabel_3.Text = 'ton/h';
            app.SPSpinner_3Label = uilabel(app.FaceplateFC2Panel);
            app.SPSpinner_3Label.Text = 'SP';
            app.SPSpinner_3 = uispinner(app.FaceplateFC2Panel);
            app.SPSpinner_3.Limits = [0 5];
            app.SPSpinner_3.ValueDisplayFormat = '%.2f';
            app.SPSpinner_3.Value = 1;
            app.Label_3 = uilabel(app.FaceplateFC2Panel);
            app.Label_3.Text = '%';
            app.MVSpinner_3Label = uilabel(app.FaceplateFC2Panel);
            app.MVSpinner_3Label.Text = 'MV';
            app.MVSpinner_3 = uispinner(app.FaceplateFC2Panel);
            app.MVSpinner_3.Limits = [0 100];
            app.MVSpinner_3.ValueDisplayFormat = '%.2f';
            app.MVSpinner_3.Value = 18.18;
            app.tonhLabel_4 = uilabel(app.FaceplateFC2Panel);
            app.tonhLabel_4.Text = 'ton/h';
            app.PVLabel_3 = uilabel(app.FaceplateFC2Panel);
            app.PVLabel_3.Text = 'PV';
            app.PV_3 = uieditfield(app.FaceplateFC2Panel, 'numeric');
            app.PV_3.Limits = [0 5];
            app.PV_3.ValueDisplayFormat = '%.2f';
            app.PV_3.Value = 1;
            app.PressodoHeaderPI1Panel = uipanel(app.ConfiguraesPanel);
            app.PressodoHeaderPI1Panel.Title = 'Pressão do Header - PI1';
            app.kgfcmLabel = uilabel(app.PressodoHeaderPI1Panel);
            app.kgfcmLabel.Text = 'kgf/cm²';
            app.P1Spinner = uispinner(app.PressodoHeaderPI1Panel);
            app.P1Spinner.Limits = [20 30];
            app.P1Spinner.Value = 30;
            app.FC2PRDCASPanel = uipanel(app.ConfiguraesPanel);
            app.FC2PRDCASPanel.Title = 'FC2 - PRD/CAS';
            app.MododeControleSwitch_2 = uiswitch(app.FC2PRDCASPanel, 'slider');
            app.MododeControleSwitch_2.Items = {'PRD', 'CAS'};
            app.MododeControleSwitch_2.Value = 'PRD';
            app.PausarSimulaoSwitchLabel = uilabel(app.ConfiguraesPanel);
            app.PausarSimulaoSwitchLabel.Text = {'Pausar'; 'Simulação'};
            app.PausarSimulaoSwitch = uiswitch(app.ConfiguraesPanel, 'slider');
            app.PausarSimulaoSwitch.Value = 'On';
            app.GrficosPanel = uipanel(app.UIFigure);
            app.GrficosPanel.Title = 'Gráficos';
            app.UIAxes2 = uiaxes(app.GrficosPanel);
            title(app.UIAxes2, 'Temperatura de Saída - TC1')
            xlabel(app.UIAxes2, 'Tempo - min')
            ylabel(app.UIAxes2, 'Temperatura - ºC')
            app.UIAxes2.XLim = [0 100];
            app.UIAxes2.YLim = [0 200];
            app.UIAxes2.XDir = 'reverse';
            app.UIAxes = uiaxes(app.GrficosPanel);
            title(app.UIAxes, 'Vazão para E-001 - FC1')
            xlabel(app.UIAxes, 'Tempo - min')
            ylabel(app.UIAxes, 'Vazão - ton/h')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 10];
            app.UIAxes.XDir = 'reverse';
            app.FC1EditField = uieditfield(app.UIFigure, 'numeric');
            app.FC1EditField.ValueDisplayFormat = '%.2f';
            app.TI1EditField = uieditfield(app.UIFigure, 'numeric');
            app.TI1EditField.ValueDisplayFormat = '%.0f';
            app.PI1EditField = uieditfield(app.UIFigure, 'numeric');
            app.PI1EditField.ValueDisplayFormat = '%.2f';
            app.FC2EditField = uieditfield(app.UIFigure, 'numeric');
            app.FC2EditField.ValueDisplayFormat = '%.2f';
            app.TI2EditField = uieditfield(app.UIFigure, 'numeric');
            app.TI2EditField.ValueDisplayFormat = '%.0f';
            app.TI3EditField = uieditfield(app.UIFigure, 'numeric');
            app.TI3EditField.ValueDisplayFormat = '%.0f';
            app.TC1EditField = uieditfield(app.UIFigure, 'numeric');
            app.TC1EditField.ValueDisplayFormat = '%.0f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CenarioB2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end