classdef CenarioC1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        PainelPanel                    matlab.ui.container.Panel
        IniciarReiniciarSimulaoButton  matlab.ui.control.Button
        FaceplateFC1Panel              matlab.ui.container.Panel
        tonhLabel                      matlab.ui.control.Label
        SPSpinnerLabel                 matlab.ui.control.Label
        SPSpinner                      matlab.ui.control.Spinner
        Label_3                        matlab.ui.control.Label
        MVSpinnerLabel                 matlab.ui.control.Label
        MVSpinner                      matlab.ui.control.Spinner
        tonhLabel_2                    matlab.ui.control.Label
        PVLabel                        matlab.ui.control.Label
        PV                             matlab.ui.control.NumericEditField
        FaceplateFC2Panel              matlab.ui.container.Panel
        tonhLabel_3                    matlab.ui.control.Label
        SPSpinner_2Label               matlab.ui.control.Label
        SPSpinner_2                    matlab.ui.control.Spinner
        Label_4                        matlab.ui.control.Label
        MVSpinner_2Label               matlab.ui.control.Label
        MVSpinner_2                    matlab.ui.control.Spinner
        tonhLabel_4                    matlab.ui.control.Label
        PVLabel_2                      matlab.ui.control.Label
        PV_2                           matlab.ui.control.NumericEditField
        FaceplateFC4Panel              matlab.ui.container.Panel
        tonhLabel_5                    matlab.ui.control.Label
        SPSpinner_3Label               matlab.ui.control.Label
        SPSpinner_4                    matlab.ui.control.Spinner
        Label_5                        matlab.ui.control.Label
        MVSpinner_3Label               matlab.ui.control.Label
        MVSpinner_4                    matlab.ui.control.Spinner
        tonhLabel_6                    matlab.ui.control.Label
        PVLabel_3                      matlab.ui.control.Label
        PV_4                           matlab.ui.control.NumericEditField
        FaceplateFC3Panel              matlab.ui.container.Panel
        tonhLabel_7                    matlab.ui.control.Label
        SPSpinner_5Label               matlab.ui.control.Label
        SPSpinner_3                    matlab.ui.control.Spinner
        Label_6                        matlab.ui.control.Label
        MVSpinner_5Label               matlab.ui.control.Label
        MVSpinner_3                    matlab.ui.control.Spinner
        tonhLabel_8                    matlab.ui.control.Label
        PVLabel_4                      matlab.ui.control.Label
        PV_3                           matlab.ui.control.NumericEditField
        FaceplateLC1Panel              matlab.ui.container.Panel
        Label_8                        matlab.ui.control.Label
        SPSpinner_5Label_2             matlab.ui.control.Label
        SPSpinner_5                    matlab.ui.control.Spinner
        Label_7                        matlab.ui.control.Label
        MVSpinner_5Label_2             matlab.ui.control.Label
        MVSpinner_5                    matlab.ui.control.Spinner
        Label_9                        matlab.ui.control.Label
        PVLabel_5                      matlab.ui.control.Label
        PV_5                           matlab.ui.control.NumericEditField
        FaceplateFC5Panel              matlab.ui.container.Panel
        tonhLabel_9                    matlab.ui.control.Label
        SPSpinner_6Label               matlab.ui.control.Label
        SPSpinner_6                    matlab.ui.control.Spinner
        Label_10                       matlab.ui.control.Label
        MVSpinner_6Label               matlab.ui.control.Label
        MVSpinner_6                    matlab.ui.control.Spinner
        tonhLabel_10                   matlab.ui.control.Label
        PVLabel_6                      matlab.ui.control.Label
        PV_6                           matlab.ui.control.NumericEditField
        FaceplateLC2Panel              matlab.ui.container.Panel
        Label_11                       matlab.ui.control.Label
        SPSpinner_7Label               matlab.ui.control.Label
        SPSpinner_7                    matlab.ui.control.Spinner
        Label_12                       matlab.ui.control.Label
        MVSpinner_7Label               matlab.ui.control.Label
        MVSpinner_7                    matlab.ui.control.Spinner
        Label_13                       matlab.ui.control.Label
        PVLabel_7                      matlab.ui.control.Label
        PV_7                           matlab.ui.control.NumericEditField
        FaceplateTC1Panel              matlab.ui.container.Panel
        CLabel                         matlab.ui.control.Label
        SPSpinner_8Label               matlab.ui.control.Label
        SPSpinner_8                    matlab.ui.control.Spinner
        Label_15                       matlab.ui.control.Label
        MVSpinner_8Label               matlab.ui.control.Label
        MVSpinner_8                    matlab.ui.control.Spinner
        CLabel_2                       matlab.ui.control.Label
        PVLabel_8                      matlab.ui.control.Label
        PV_8                           matlab.ui.control.NumericEditField
        FaceplatePC1Panel              matlab.ui.container.Panel
        barLabel                       matlab.ui.control.Label
        SPSpinner_9Label               matlab.ui.control.Label
        SPSpinner_9                    matlab.ui.control.Spinner
        Label_18                       matlab.ui.control.Label
        MVSpinner_9Label               matlab.ui.control.Label
        MVSpinner_9                    matlab.ui.control.Spinner
        barLabel_2                     matlab.ui.control.Label
        PVLabel_9                      matlab.ui.control.Label
        PV_9                           matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        PausarSimulaoSwitchLabel       matlab.ui.control.Label
        PausarSimulaoSwitch            matlab.ui.control.Switch
        AIAEditFieldLabel              matlab.ui.control.Label
        AIAEditField                   matlab.ui.control.NumericEditField
        AIBEditField                   matlab.ui.control.NumericEditField
        AIBEditFieldLabel              matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        FI1EditField                   matlab.ui.control.NumericEditField
        FI2EditField                   matlab.ui.control.NumericEditField
        FI4EditField                   matlab.ui.control.NumericEditField
        TI1EditField                   matlab.ui.control.NumericEditField
        TI3EditField                   matlab.ui.control.NumericEditField
        TI2EditField                   matlab.ui.control.NumericEditField
        FI3EditField                   matlab.ui.control.NumericEditField
        LI1EditField                   matlab.ui.control.NumericEditField
        FI5EditField                   matlab.ui.control.NumericEditField
        LI2EditField                   matlab.ui.control.NumericEditField
        PI1EditField                   matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarReiniciarSimulaoButton
        function IniciarReiniciarSimulaoButtonPushed(app, event)
            %------------------ Desenvolvido por Filipe Pacheco ----------------------%
            clc
            %-------------------------Parâmetros Iniciais-----------------------------%
            
            Fi  =   10;      %Vazão de carga;
            F   =   Fi;
            APi =   .7;      %Composição de A na carga;
            AP  =   APi;
            BPi =   .3;      %Composição de B na carga;
            BP  =   BPi;
            Ri  =   20;      %Vazão de Refluxo;
            R   =   Ri;
            Vi  =   04;      %Vazão de vapor;
            V   =   Vi;
            LAA =   5.4691;      %Vazão de líquido ascendente - A
            LAB =   1.0012;      %Vazão de líquido ascendente - B
            LDA =   1.5351;      %Vazão de líquido descendente - A
            LDB =   1.9987;      %Vazão de líquido descendente - B
            NVT =   05;          %Nível do vaso de topo;
            NVF =   05;          %Nível de vaso de fundo;
            AI1 =   100*LAA(end)/(LAA(end)+LAB(end));
            AI2 =   100*LAB(end)/(LAA(end)+LAB(end));
            
            P   =   01;         %Pressão de operação da coluna;
            CvP =   47.4;       %Área de escoamento da pressão;
            
            CvP1 = 500;         %Área de sucção da P-101;
            CvP2 = 400;         %Área de descarga da P-101;
            PP1  = NVT(end);    %NPSH da P-101;
            PP2  = 33.1780;     %Pressão de descarga da P-101;
            PP3  = P;           %Pressão a montante de Válvula;
            
            CvP3 = 200;         %Área de sucção da P-102;
            CvP4 = 100;         %Área de descarga da P-102;
            PP4  = NVF(end);    %NPSH da P-102;
            PP5  = 33.1780;     %Pressão de descarga da P-102;
            PP6  = 01;          %Pressão a montante de Válvula;
            
            Ro   =  999;        %Peso específico do fluído destilado;
            Cv11 =  300;        %Área de escoamento FC-1 - Montante;
            Cv12 =  3*24.91;    %Área de escoamento FC-1 - Válvula;
            P11  =  020;        %Pressão de escoamento FC-1 - Montante;
            P12  =  18.872;     %Pressão de escoamento FC-1 - Válvula;
            P13  =  P;          %Pressão de escoamento FC-1 - Torre;
            
            Cv21 =  400;        %Área de escoamento FC-2 - Montante;
            Cv22 =  4*28.9270;  %Área de escoamento FC-2 - Válvula;
            P21  =  PP2;        %Pressão de escoamento FC-2 - Montante;
            P22  =  33.3452;    %Pressão de escoamento FC-2 - Válvula;
            P23  =  P;          %Pressão de escoamento FC-2 - Torre;
            
            Cv31 =  200;        %Área de escoamento FC-3 - Montante;
            Cv32 =  2*17.1142;  %Área de escoamento FC-2 - Válvula;
            P31  =  PP2;        %Pressão de escoamento FC-3 - P-101;
            P32  =  32;         %Pressão de escoamento FC-3 - Válvula;
            P33  =  001;        %Pressão de escoamento FC-3 - Header final - A;
            
            Cv41 =  100;        %Área de escoamento FC-4 - Montante;
            Cv42 =  50;         %Área de escoamento FC-4 - Válvula;
            P41  =  020;        %Pressão de escoamento FC-4 - Header distribuidor;
            P42  =  16.8347;    %Pressão de escoamento FC-4 - Válvula;
            P43  =  005;        %Pressão de escoamento FC-4 - Header coletor;
            
            Cv51 =  100;        %Área de escoamento FC-5 - Montante;
            Cv52 =  19.68;      %Área de escoamento FC-5 - Válvula;
            P51  =  PP5;        %Pressão de escoamento FC-5 - P-102;
            P52  =  31.9907;    %Pressão de escoamento FC-5 - Válvula;
            P53  =  001;        %Pressão de escoamento FC-5 - Header final - B;
            
            Erro = [0 0 0 0 0 0 0 0 0];
            dErro= [0 0 0 0 0 0 0 0 0];
            
            PB1  = 500;
            TI1  = 500;
            TD1  = 000;
            PB2  = 300;
            TI2  = 300;
            TD2  = 000;
            PB3  = 900;
            TI3  = 600;
            TD3  = 000;
            PB4  = 300;
            TI4  = 300;
            TD4  = 000;
            PB5  = 500;
            TI5  = 600;
            TD5  = 020;
            PB6  = 500;
            TI6  = 400;
            TD6  = 000;
            PB7  = 999;
            TI7  = 999;
            TD7  = 050;
            PB8  = 999;
            TI8  = 400;
            TD8  = 000;
            PB9  = 999;
            TI9  = 400;
            TD9  = 000;
            
            MV1  = Cv12/3;
            MV2  = Cv22/4;
            MV3  = Cv32/2;
            MV4  = Cv42;
            MV5  = 059.7;
            MV6  = Cv52;
            MV7  = 35;
            MV8  = 40;
            MV9  = CvP;
            
            MV   =[MV1 MV2 MV3 MV4 MV5 MV6 MV7 MV8 MV9];
            PB   =[PB1 PB2 PB3 PB4 PB5 PB6 PB7 PB8 PB9];
            TI   =[TI1 TI2 TI3 TI4 TI5 TI6 TI7 TI8 TI9];
            TD   =[TD1 TD2 TD3 TD4 TD5 TD6 TD7 TD8 TD9];
            
            Q1  = Cv12*sqrt((P12(end)-P13)/Ro);
            Q2  = Cv22*sqrt((P22(end)-P23)/Ro);
            Q3  = Cv32*sqrt((P32(end)-P33)/Ro);
            Q4  = Cv42*sqrt((P42(end)-P43)/(2*Ro));
            Q5  = Cv52*sqrt((P52(end)-P53)/(Ro));
            
            
            for i=1:09
                V=[V Vi];
            end
            for i=1:29
                R=[R Ri];
            end
            for i=1:19
                AP=[AP APi];
            end
            for i=1:19
                BP=[BP BPi];
            end
            for i=1:14
                F=[F Fi];
            end
            
            TRAN    = 200;            % Time Range

            AI1_H   = AI1*ones(1,TRAN);
            AI2_H   = AI2*ones(1,TRAN);
            
            time = 0:1:TRAN-1;
            
            p1 = plot(app.UIAxes,time,AI1_H(1:TRAN),time,AI2_H(1,1:TRAN));
            
            %-------------------------------------------------------------------------%
            
            for t=1:1:100000
                if app.PausarSimulaoSwitch.Value == "Off"
                    pause(1)
                elseif app.PausarSimulaoSwitch.Value == "On"
                    tic
                
                    for i=1:1
                        
                        REF   = [app.SPSpinner.Value app.SPSpinner_2.Value app.SPSpinner_3.Value app.SPSpinner_4.Value app.SPSpinner_5.Value app.SPSpinner_6.Value app.SPSpinner_7.Value app.SPSpinner_8.Value app.SPSpinner_9.Value];
                        
                        [LAA,LDA]  = SIM1(F,AP,R,V,P,LAA,LDA);             %Vazão de líquido Ascendente e Descendente - A
                        [LAB,LDB]  = SIM2(F,BP,R,V,P,LAB,LDB);             %Vazão de líquido Ascendente e Descendente - B
                        [NVT,NVF]  = SIM3(LAA,LDA,LAB,LDB,NVT,NVF,Q3,Q5);  %Nível de topo e de fundo
                        [P12]      = SIM4(P12,P11,P13,Cv11,Cv12);          %Vazão de escoamento na carga
                        [P22]      = SIM5(P22,P21,P23,Cv21,Cv22);          %Vazão de escoamento no Refluxo
                        [P32]      = SIM6(P32,P31,P33,Cv31,Cv32);          %Vazão de vapor
                        [P42]      = SIM7(P42,P41,P43,Cv41,Cv42);          %Vazão de escoamento na retirada de topo
                        [P52]      = SIM8(P52,P51,P53,Cv51,Cv52);          %Vazão de escoamento na retirada de fundo
                        [P]        = SIM9(P,LAA,LAB,CvP);                  %Pressão da coluna
                        [PP2]      = SIM10(PP1,PP2,PP3,CvP1,CvP2);         %Vazão da P-101;
                        [PP5]      = SIM11(PP4,PP5,PP6,CvP3,CvP4);         %Vazão da P-102;
                        
                        Q1  = Cv12*sqrt((P12(end)-P13)/Ro);
                        Q2  = Cv22*sqrt((P22(end)-P23)/Ro);
                        Q3  = Cv32*sqrt((P32(end)-P33)/Ro);
                        Q4  = Cv42*sqrt((P42(end)-P43)/(2*Ro));
                        Q5  = Cv52*sqrt((P52(end)-P53)/(Ro));
                        
                        P13 = P(end);
                        P23 = P(end);
                        PP1 = NVT(end);
                        PP4 = NVF(end);
                        PP3 = P(end);
                        P21 = PP2(end);
                        P31 = PP2(end);
                        
                        SUM = (MV./(100./PB) + Erro - TD.*dErro)./(1./TI);                  % Cálculo para troca de Manual para Auto
                        SUM = min(TI.*(PB-Erro- TD.*dErro), max(TI.*(-Erro-TD.*dErro),SUM));% Limitação máxima e mínima do SUM.
                        
                        dErro = ((REF-[Q1 Q2 Q3 Q4 NVT(end) Q5 NVF(end) 50*tanh(.75*LAB(end)./(LAA(end)+.01))+150 P(end)]) - Erro)*[.5 0 0 0 0 0 0 0 0; 0 .3 0 0 0 0 0 0 0; 0 0 1 0 0 0 0 0 0;0 0 0 1 0 0 0 0 0;0 0 0 0 -1 0 0 0 0;0 0 0 0 0 1 0 0 0;0 0 0 0 0 0 -1 0 0; 0 0 0 0 0 0 0 .05 0;0 0 0 0 0 0 0 0 -10];       % Cálculo da Variação do Erro
                        Erro  = (REF-[Q1 Q2 Q3 Q4 NVT(end) Q5 NVF(end) 50*tanh(.75*LAB(end)./(LAA(end)+.01))+150 P(end)])*[5 0 0 0 0 0 0 0 0; 0 3.3 0 0 0 0 0 0 0; 0 0 10 0 0 0 0 0 0; 0 0 0 10 0 0 0 0 0; 0 0 0 0 -10 0 0 0 0; 0 0 0 0 0 10 0 0 0; 0 0 0 0 0 0 -10 0 0; 0 0 0 0 0 0 0 .5 0; 0 0 0 0 0 0 0 0 -100];                    % Cálculo do Erro
                        SUM   = SUM + Erro;                                                 % Cálculo do Controlador PID - Integral
                        
                        MV    = (100./PB).*(Erro +(1./TI).*SUM +TD.*dErro);                 % Cálculo do Controlador PID - Soma das Três partes
                        MV    = min(100, max(0.0001,MV));                                   % Limitação máxima e mínima das MV.
                        
                        F   = [F(2:size(F,2)) Q1];
                        V   = [V(2:size(V,2)) Q4];
                        R   = [R(2:size(F,2)) Q2];
                        AP  = [AP(2:size(AP,2)) APi];
                        BP  = [BP(2:size(BP,2)) BPi];
                        
                        AI1_H(1:TRAN) = [AI1_H(2:TRAN) 100*LAA(end)/(LAA(end)+LAB(end))];
                        AI2_H(1:TRAN) = [AI2_H(2:TRAN) 100*LAB(end)/(LAA(end)+LAB(end))];
                      
%                         AI1 = [AI1 100*LAA(end)/(LAA(end)+LAB(end))];
%                         AI2 = [AI2 100*LAB(end)/(LAA(end)+LAB(end))];
                        
                        app.AIAEditField.Value = 100*LAA(end)/(LAA(end)+LAB(end));
                        app.AIBEditField.Value = 100*LAB(end)/(LAA(end)+LAB(end));
                        app.TI2EditField.Value = 30*tanh(.65*LAB(end)./(LAA(end)+.01))+100;
                        app.TI1EditField.Value = 50*tanh(.75*LAB(end)./(LAA(end)+.01))+150;
                        app.TI3EditField.Value = 100*tanh(1.2*LDB(end)./(LDA(end)+.01))+200;
                        app.LI1EditField.Value = 10*NVT(end);
                        app.LI2EditField.Value = 10*NVF(end);
                        app.PI1EditField.Value = P(end);
                        app.FI1EditField.Value = Q1;
                        app.FI2EditField.Value = Q2;
                        app.FI3EditField.Value = Q3;
                        app.FI4EditField.Value = Q4;
                        app.FI5EditField.Value = Q5;
                        app.PV.Value = Q1;
                        app.PV_2.Value = Q2;
                        app.PV_3.Value = Q3;
                        app.PV_4.Value = Q4;
                        app.PV_5.Value = NVT(end);
                        app.PV_6.Value = Q5;
                        app.PV_7.Value = NVF(end);
                        app.PV_8.Value = 50*tanh(.75*LAB(end)./(LAA(end)+.01))+150;
                        app.PV_9.Value = P(end);
                        
                        app.MVSpinner.Value = MV(1);
                        app.MVSpinner_2.Value = MV(2);
                        app.MVSpinner_3.Value = MV(3);
                        app.MVSpinner_4.Value = MV(4);
                        app.MVSpinner_5.Value = MV(5);
                        app.MVSpinner_6.Value = MV(6);
                        app.MVSpinner_7.Value = MV(7);
                        app.MVSpinner_8.Value = MV(8);
                        app.MVSpinner_9.Value = MV(9);
                        
                        Cv12  = 3*app.MVSpinner.Value;
                        Cv22  = 4*app.MVSpinner_2.Value;
                        Cv32  = 2*app.MVSpinner_3.Value;
                        Cv42  = 1*app.MVSpinner_4.Value;
                        Cv52  = 1*app.MVSpinner_6.Value;
                        CvP   = 1*app.MVSpinner_9.Value;
                        
                        app.SPSpinner_3.Value = .1*app.MVSpinner_5.Value;
                        app.SPSpinner_4.Value = .1*app.MVSpinner_8.Value;
                        app.SPSpinner_6.Value = .1*app.MVSpinner_7.Value;
                        
                    end
                    
                    p1(1).YData = flip(AI1_H(1:TRAN));
                    p1(2).YData = flip(AI2_H(1:TRAN));
                    drawnow;  
                    
                    pause(max(1-toc,0));
                end
            end
            
            %--------------------------------Funções----------------------------------%
            
            function[LAA,LDA] = SIM1(F,AP,R,V,P,LAA,LDA)
                [t,x]= ode15s(@(t,LAA) .035*(tanh(09*AP(1)*(V(1)/R(1))-.0005*F(1)-.2*P(end))*F(1)*AP(1) -LAA),[0:.5:1],LAA(end));
                LAA=[LAA x(end)];
                [t,x]= ode15s(@(t,LDA) .015*((1-(tanh(09*AP(1)*(V(1)/R(1))-.0005*F(1)-.2*P(end))))*F(1)*AP(1) -LDA),[0:.5:1],LDA(end));
                LDA=[LDA x(end)];
            end
            
            function[LAB,LDB] = SIM2(F,BP,R,V,P,LAB,LDB)
                [t,x]= ode15s(@(t,LAB) .1*(tanh(11*BP(1)*(V(1)/R(1))-.03*F(1)-.01*P(end))*F(1)*BP(1) -LAB),[0:.5:1],LAB(end));
                LAB=[LAB x(end)];
                [t,x]= ode45(@(t,LDB) .08*((1-tanh(11*BP(1)*(V(1)/R(1))-.03*F(1)-.01*P(end)))*F(1)*BP(1) -LDB),[0:.5:1],LDB(end));
                LDB=[LDB x(end)];
            end
            
            function[NVT,NVF] = SIM3(LAA,LDA,LAB,LDB,NVT,NVF,Q3,Q5)
                [t,x]= ode15s(@(t,NVT) .05*((LAA(end)+LAB(end)) -1*Q3),[0:.5:1],NVT(end));
                NVT=[NVT x(end)];
                
                [t,x]= ode15s(@(t,NVF) .05*(LDA(end)+LDB(end) - Q5),[0:.5:1],NVF(end));
                NVF=[NVF x(end)];
            end
            
            function[P12] = SIM4(P12,P11,P13,Cv11,Cv12)
                [t,x]= ode15s(@(t,P12) Cv11^2*P11 + Cv12^2*P13 -(Cv11^2+Cv12^2)*P12,[0:.5:1],P12(end));
                P12=[P12 x(end)];
            end
            
            function[P22] = SIM5(P22,P21,P23,Cv21,Cv22)
                [t,x]= ode15s(@(t,P22) Cv21^2*P21 + Cv22^2*P23 -(Cv21^2+Cv22^2)*P22,[0:.5:1],P22(end));
                P22=[P22 x(end)];
            end
            
            function[P32] = SIM6(P32,P31,P33,Cv31,Cv32)
                [t,x]= ode15s(@(t,P32) Cv31^2*P31 + Cv32^2*P33 -(Cv31^2+Cv32^2)*P32,[0:.5:1],P32(end));
                P32=[P32 x(end)];
            end
            
            function[P42] = SIM7(P42,P41,P43,Cv41,Cv42)
                [t,x]= ode15s(@(t,P42) Cv41^2*P41 + Cv42^2*P43 -(Cv41^2+Cv42^2)*P42,[0:.5:1],P42(end));
                P42=[P42 x(end)];
            end
            
            function[P52] = SIM8(P52,P51,P53,Cv51,Cv52)
                [t,x]= ode15s(@(t,P52) Cv51^2*P51 + Cv52^2*P53 -(Cv51^2+Cv52^2)*P52,[0:.5:1],P52(end));
                P52=[P52 x(end)];
            end
            
            function[P] = SIM9(P,LAA,LAB,CvP)
                [t,x]= ode15s(@(t,P) .1*(8*(LAA(end)+LAB(end)) -CvP*P),[0:.5:1],P(end));
                P=[P x(end)];
            end
            
            function[PP2] = SIM10(PP1,PP2,PP3,CvP1,CvP2)
                [t,x]= ode15s(@(t,PP2) (CvP1^2*7*3+10e1*PP1) -.5*CvP2^2*PP3  -CvP2^2*PP2,[0:.5:1],PP2(end));
                if PP1<.01
                    x = 0;
                end
                PP2=[PP2 x(end)];
            end
            
            function[PP5] = SIM11(PP4,PP5,PP6,CvP3,CvP4)
                [t,x]= ode15s(@(t,PP5) (CvP3^2*4*3+10e0*PP4) -20*CvP4^2*PP6  -CvP4^2*PP5,[0:.5:1],PP5(end));
                if PP4<.01
                    x = 0;
                end
                PP5=[PP5 x(end)];
            end
            
            %-------------------------------------------------------------------------%
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'T1.png';
            app.PainelPanel = uipanel(app.UIFigure);
            app.PainelPanel.Title = 'Painel';
            app.IniciarReiniciarSimulaoButton = uibutton(app.PainelPanel, 'push');
            app.IniciarReiniciarSimulaoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarReiniciarSimulaoButtonPushed, true);
            app.IniciarReiniciarSimulaoButton.Text = {'Iniciar/ Reiniciar '; 'Simulação'};
            app.FaceplateFC1Panel = uipanel(app.PainelPanel);
            app.FaceplateFC1Panel.Title = 'Faceplate - FC1';
            app.tonhLabel = uilabel(app.FaceplateFC1Panel);
            app.tonhLabel.Text = 'ton/h';
            app.SPSpinnerLabel = uilabel(app.FaceplateFC1Panel);
            app.SPSpinnerLabel.Text = 'SP';
            app.SPSpinner = uispinner(app.FaceplateFC1Panel);
            app.SPSpinner.Limits = [0 20];
            app.SPSpinner.Value = 10;
            app.Label_3 = uilabel(app.FaceplateFC1Panel);
            app.Label_3.Text = '%';
            app.MVSpinnerLabel = uilabel(app.FaceplateFC1Panel);
            app.MVSpinnerLabel.Text = 'MV';
            app.MVSpinner = uispinner(app.FaceplateFC1Panel);
            app.MVSpinner.Limits = [0 100];
            app.MVSpinner.ValueDisplayFormat = '%.2f';
            app.MVSpinner.Value = 38.932;
            app.tonhLabel_2 = uilabel(app.FaceplateFC1Panel);
            app.tonhLabel_2.Text = 'ton/h';
            app.PVLabel = uilabel(app.FaceplateFC1Panel);
            app.PVLabel.Text = 'PV';
            app.PV = uieditfield(app.FaceplateFC1Panel, 'numeric');
            app.PV.Limits = [0 30];
            app.PV.ValueDisplayFormat = '%.2f';
            app.PV.Value = 5;
            app.FaceplateFC2Panel = uipanel(app.PainelPanel);
            app.FaceplateFC2Panel.Title = 'Faceplate - FC2';
            app.tonhLabel_3 = uilabel(app.FaceplateFC2Panel);
            app.tonhLabel_3.Text = 'ton/h';
            app.SPSpinner_2Label = uilabel(app.FaceplateFC2Panel);
            app.SPSpinner_2Label.Text = 'SP';
            app.SPSpinner_2 = uispinner(app.FaceplateFC2Panel);
            app.SPSpinner_2.Limits = [0 30];
            app.SPSpinner_2.Value = 15;
            app.Label_4 = uilabel(app.FaceplateFC2Panel);
            app.Label_4.Text = '%';
            app.MVSpinner_2Label = uilabel(app.FaceplateFC2Panel);
            app.MVSpinner_2Label.Text = 'MV';
            app.MVSpinner_2 = uispinner(app.FaceplateFC2Panel);
            app.MVSpinner_2.Limits = [0 100];
            app.MVSpinner_2.ValueDisplayFormat = '%.2f';
            app.MVSpinner_2.Value = 38.932;
            app.tonhLabel_4 = uilabel(app.FaceplateFC2Panel);
            app.tonhLabel_4.Text = 'ton/h';
            app.PVLabel_2 = uilabel(app.FaceplateFC2Panel);
            app.PVLabel_2.Text = 'PV';
            app.PV_2 = uieditfield(app.FaceplateFC2Panel, 'numeric');
            app.PV_2.Limits = [0 30];
            app.PV_2.ValueDisplayFormat = '%.2f';
            app.PV_2.Value = 5;
            app.FaceplateFC4Panel = uipanel(app.PainelPanel);
            app.FaceplateFC4Panel.Title = 'Faceplate - FC4';
            app.tonhLabel_5 = uilabel(app.FaceplateFC4Panel);
            app.tonhLabel_5.Text = 'ton/h';
            app.SPSpinner_3Label = uilabel(app.FaceplateFC4Panel);
            app.SPSpinner_3Label.Text = 'SP';
            app.SPSpinner_4 = uispinner(app.FaceplateFC4Panel);
            app.SPSpinner_4.Limits = [0 10];
            app.SPSpinner_4.Value = 3;
            app.Label_5 = uilabel(app.FaceplateFC4Panel);
            app.Label_5.Text = '%';
            app.MVSpinner_3Label = uilabel(app.FaceplateFC4Panel);
            app.MVSpinner_3Label.Text = 'MV';
            app.MVSpinner_4 = uispinner(app.FaceplateFC4Panel);
            app.MVSpinner_4.Limits = [0 100];
            app.MVSpinner_4.ValueDisplayFormat = '%.2f';
            app.MVSpinner_4.Value = 38.932;
            app.tonhLabel_6 = uilabel(app.FaceplateFC4Panel);
            app.tonhLabel_6.Text = 'ton/h';
            app.PVLabel_3 = uilabel(app.FaceplateFC4Panel);
            app.PVLabel_3.Text = 'PV';
            app.PV_4 = uieditfield(app.FaceplateFC4Panel, 'numeric');
            app.PV_4.Limits = [0 30];
            app.PV_4.ValueDisplayFormat = '%.2f';
            app.PV_4.Value = 3;
            app.FaceplateFC3Panel = uipanel(app.PainelPanel);
            app.FaceplateFC3Panel.Title = 'Faceplate - FC3';
            app.tonhLabel_7 = uilabel(app.FaceplateFC3Panel);
            app.tonhLabel_7.Text = 'ton/h';
            app.SPSpinner_5Label = uilabel(app.FaceplateFC3Panel);
            app.SPSpinner_5Label.Text = 'SP';
            app.SPSpinner_3 = uispinner(app.FaceplateFC3Panel);
            app.SPSpinner_3.Limits = [0 30];
            app.SPSpinner_3.Value = 3;
            app.Label_6 = uilabel(app.FaceplateFC3Panel);
            app.Label_6.Text = '%';
            app.MVSpinner_5Label = uilabel(app.FaceplateFC3Panel);
            app.MVSpinner_5Label.Text = 'MV';
            app.MVSpinner_3 = uispinner(app.FaceplateFC3Panel);
            app.MVSpinner_3.Limits = [0 100];
            app.MVSpinner_3.ValueDisplayFormat = '%.2f';
            app.MVSpinner_3.Value = 38.932;
            app.tonhLabel_8 = uilabel(app.FaceplateFC3Panel);
            app.tonhLabel_8.Text = 'ton/h';
            app.PVLabel_4 = uilabel(app.FaceplateFC3Panel);
            app.PVLabel_4.Text = 'PV';
            app.PV_3 = uieditfield(app.FaceplateFC3Panel, 'numeric');
            app.PV_3.Limits = [0 30];
            app.PV_3.ValueDisplayFormat = '%.2f';
            app.PV_3.Value = 3;
            app.FaceplateLC1Panel = uipanel(app.PainelPanel);
            app.FaceplateLC1Panel.Title = 'Faceplate - LC1';
            app.Label_8 = uilabel(app.FaceplateLC1Panel);
            app.Label_8.Text = '%';
            app.SPSpinner_5Label_2 = uilabel(app.FaceplateLC1Panel);
            app.SPSpinner_5Label_2.Text = 'SP';
            app.SPSpinner_5 = uispinner(app.FaceplateLC1Panel);
            app.SPSpinner_5.Limits = [0 10];
            app.SPSpinner_5.Value = 5;
            app.Label_7 = uilabel(app.FaceplateLC1Panel);
            app.Label_7.Text = '%';
            app.MVSpinner_5Label_2 = uilabel(app.FaceplateLC1Panel);
            app.MVSpinner_5Label_2.Text = 'MV';
            app.MVSpinner_5 = uispinner(app.FaceplateLC1Panel);
            app.MVSpinner_5.Limits = [0 100];
            app.MVSpinner_5.ValueDisplayFormat = '%.2f';
            app.MVSpinner_5.Value = 5;
            app.Label_9 = uilabel(app.FaceplateLC1Panel);
            app.Label_9.Text = '%';
            app.PVLabel_5 = uilabel(app.FaceplateLC1Panel);
            app.PVLabel_5.Text = 'PV';
            app.PV_5 = uieditfield(app.FaceplateLC1Panel, 'numeric');
            app.PV_5.ValueDisplayFormat = '%.2f';
            app.PV_5.Value = 5;
            app.FaceplateFC5Panel = uipanel(app.PainelPanel);
            app.FaceplateFC5Panel.Title = 'Faceplate - FC5';
            app.tonhLabel_9 = uilabel(app.FaceplateFC5Panel);
            app.tonhLabel_9.Text = 'ton/h';
            app.SPSpinner_6Label = uilabel(app.FaceplateFC5Panel);
            app.SPSpinner_6Label.Text = 'SP';
            app.SPSpinner_6 = uispinner(app.FaceplateFC5Panel);
            app.SPSpinner_6.Limits = [0 10];
            app.SPSpinner_6.Value = 3;
            app.Label_10 = uilabel(app.FaceplateFC5Panel);
            app.Label_10.Text = '%';
            app.MVSpinner_6Label = uilabel(app.FaceplateFC5Panel);
            app.MVSpinner_6Label.Text = 'MV';
            app.MVSpinner_6 = uispinner(app.FaceplateFC5Panel);
            app.MVSpinner_6.Limits = [0 100];
            app.MVSpinner_6.ValueDisplayFormat = '%.2f';
            app.MVSpinner_6.Value = 38.932;
            app.tonhLabel_10 = uilabel(app.FaceplateFC5Panel);
            app.tonhLabel_10.Text = 'ton/h';
            app.PVLabel_6 = uilabel(app.FaceplateFC5Panel);
            app.PVLabel_6.Text = 'PV';
            app.PV_6 = uieditfield(app.FaceplateFC5Panel, 'numeric');
            app.PV_6.Limits = [0 30];
            app.PV_6.ValueDisplayFormat = '%.2f';
            app.PV_6.Value = 3;
            app.FaceplateLC2Panel = uipanel(app.PainelPanel);
            app.FaceplateLC2Panel.Title = 'Faceplate - LC2';
            app.Label_11 = uilabel(app.FaceplateLC2Panel);
            app.Label_11.Text = '%';
            app.SPSpinner_7Label = uilabel(app.FaceplateLC2Panel);
            app.SPSpinner_7Label.Text = 'SP';
            app.SPSpinner_7 = uispinner(app.FaceplateLC2Panel);
            app.SPSpinner_7.Limits = [0 10];
            app.SPSpinner_7.Value = 5;
            app.Label_12 = uilabel(app.FaceplateLC2Panel);
            app.Label_12.Text = '%';
            app.MVSpinner_7Label = uilabel(app.FaceplateLC2Panel);
            app.MVSpinner_7Label.Text = 'MV';
            app.MVSpinner_7 = uispinner(app.FaceplateLC2Panel);
            app.MVSpinner_7.Limits = [0 100];
            app.MVSpinner_7.ValueDisplayFormat = '%.2f';
            app.MVSpinner_7.Value = 5;
            app.Label_13 = uilabel(app.FaceplateLC2Panel);
            app.Label_13.Text = '%';
            app.PVLabel_7 = uilabel(app.FaceplateLC2Panel);
            app.PVLabel_7.Text = 'PV';
            app.PV_7 = uieditfield(app.FaceplateLC2Panel, 'numeric');
            app.PV_7.ValueDisplayFormat = '%.2f';
            app.PV_7.Value = 5;
            app.FaceplateTC1Panel = uipanel(app.PainelPanel);
            app.FaceplateTC1Panel.Title = 'Faceplate - TC1';
            app.CLabel = uilabel(app.FaceplateTC1Panel);
            app.CLabel.Text = 'ºC';
            app.SPSpinner_8Label = uilabel(app.FaceplateTC1Panel);
            app.SPSpinner_8Label.Text = 'SP';
            app.SPSpinner_8 = uispinner(app.FaceplateTC1Panel);
            app.SPSpinner_8.Limits = [0 200];
            app.SPSpinner_8.Value = 156;
            app.Label_15 = uilabel(app.FaceplateTC1Panel);
            app.Label_15.Text = '%';
            app.MVSpinner_8Label = uilabel(app.FaceplateTC1Panel);
            app.MVSpinner_8Label.Text = 'MV';
            app.MVSpinner_8 = uispinner(app.FaceplateTC1Panel);
            app.MVSpinner_8.Limits = [0 100];
            app.MVSpinner_8.ValueDisplayFormat = '%.2f';
            app.MVSpinner_8.Value = 5;
            app.CLabel_2 = uilabel(app.FaceplateTC1Panel);
            app.CLabel_2.Text = 'ºC';
            app.PVLabel_8 = uilabel(app.FaceplateTC1Panel);
            app.PVLabel_8.Text = 'PV';
            app.PV_8 = uieditfield(app.FaceplateTC1Panel, 'numeric');
            app.PV_8.ValueDisplayFormat = '%.2f';
            app.PV_8.Value = 156;
            app.FaceplatePC1Panel = uipanel(app.PainelPanel);
            app.FaceplatePC1Panel.Title = 'Faceplate - PC1';
            app.barLabel = uilabel(app.FaceplatePC1Panel);
            app.barLabel.Text = 'bar';
            app.SPSpinner_9Label = uilabel(app.FaceplatePC1Panel);
            app.SPSpinner_9Label.Text = 'SP';
            app.SPSpinner_9 = uispinner(app.FaceplatePC1Panel);
            app.SPSpinner_9.Limits = [0 10];
            app.SPSpinner_9.Value = 1;
            app.Label_18 = uilabel(app.FaceplatePC1Panel);
            app.Label_18.Text = '%';
            app.MVSpinner_9Label = uilabel(app.FaceplatePC1Panel);
            app.MVSpinner_9Label.Text = 'MV';
            app.MVSpinner_9 = uispinner(app.FaceplatePC1Panel);
            app.MVSpinner_9.Limits = [0 100];
            app.MVSpinner_9.ValueDisplayFormat = '%.2f';
            app.MVSpinner_9.Value = 5;
            app.barLabel_2 = uilabel(app.FaceplatePC1Panel);
            app.barLabel_2.Text = 'bar';
            app.PVLabel_9 = uilabel(app.FaceplatePC1Panel);
            app.PVLabel_9.Text = 'PV';
            app.PV_9 = uieditfield(app.FaceplatePC1Panel, 'numeric');
            app.PV_9.ValueDisplayFormat = '%.2f';
            app.PV_9.Value = 1;
            app.UIAxes = uiaxes(app.PainelPanel);
            title(app.UIAxes, 'Concentração no Topo - AI1 / AI2')
            xlabel(app.UIAxes, 'Tempo - min')
            ylabel(app.UIAxes, 'Concentração - %')
            app.UIAxes.XLim = [0 200];
            app.UIAxes.YLim = [0 100];
            app.UIAxes.XDir = 'reverse';
            app.PausarSimulaoSwitchLabel = uilabel(app.PainelPanel);
            app.PausarSimulaoSwitchLabel.Text = {'Pausar'; 'Simulação'};
            app.PausarSimulaoSwitch = uiswitch(app.PainelPanel, 'slider');
            app.PausarSimulaoSwitch.Value = 'On';
            app.AIAEditFieldLabel = uilabel(app.UIFigure);
            app.AIAEditFieldLabel.Text = 'AI-A';
            app.AIAEditField = uieditfield(app.UIFigure, 'numeric');
            app.AIAEditField.ValueDisplayFormat = '%.1f';
            app.AIBEditField = uieditfield(app.UIFigure, 'numeric');
            app.AIBEditField.ValueDisplayFormat = '%.1f';
            app.AIBEditFieldLabel = uilabel(app.UIFigure);
            app.AIBEditFieldLabel.Text = 'AI-B';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '%';
            app.FI1EditField = uieditfield(app.UIFigure, 'numeric');
            app.FI1EditField.ValueDisplayFormat = '%.1f';
            app.FI2EditField = uieditfield(app.UIFigure, 'numeric');
            app.FI2EditField.ValueDisplayFormat = '%.1f';
            app.FI4EditField = uieditfield(app.UIFigure, 'numeric');
            app.FI4EditField.ValueDisplayFormat = '%.1f';
            app.TI1EditField = uieditfield(app.UIFigure, 'numeric');
            app.TI1EditField.ValueDisplayFormat = '%.1f';
            app.TI3EditField = uieditfield(app.UIFigure, 'numeric');
            app.TI3EditField.ValueDisplayFormat = '%.1f';
            app.TI2EditField = uieditfield(app.UIFigure, 'numeric');
            app.TI2EditField.ValueDisplayFormat = '%.1f';
            app.FI3EditField = uieditfield(app.UIFigure, 'numeric');
            app.FI3EditField.ValueDisplayFormat = '%.1f';
            app.LI1EditField = uieditfield(app.UIFigure, 'numeric');
            app.LI1EditField.ValueDisplayFormat = '%.1f';
            app.FI5EditField = uieditfield(app.UIFigure, 'numeric');
            app.FI5EditField.ValueDisplayFormat = '%.1f';
            app.LI2EditField = uieditfield(app.UIFigure, 'numeric');
            app.LI2EditField.ValueDisplayFormat = '%.1f';
            app.PI1EditField = uieditfield(app.UIFigure, 'numeric');
            app.PI1EditField.ValueDisplayFormat = '%.1f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CenarioC1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end